classdef ADC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image3                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Label                    matlab.ui.control.Label
        DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label  matlab.ui.control.Label
        BitCountEditField        matlab.ui.control.EditField
        BitCountEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.EditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        CONVERTButton            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONVERTButton
        function CONVERTButtonPushed(app, event)
cla(app.UIAxes);
f = str2num(app.FrequencyEditField.Value) ;
n = str2num(app.BitCountEditField.Value) ;
q = f/2^(n-1) ;
t = 0:0.1:f ;
y = abs((f/2) * sin(t));

x0 = fix(y/q);

y0 = dec2bin(x0,n) ;
y1 = x0 * q;
plot(app.UIAxes,t, y, 'r', 'Linewidth', 3);
%plot (t,y,'r') ;
hold(app.UIAxes, 'on');
%plot (t, y1,'b')  ;
plot(app.UIAxes,t, y1, 'b', 'Linewidth', 3);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            
        end

        % Value changed function: BitCountEditField
        function BitCountEditFieldValueChanged(app, event)
            value = app.BitCountEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ANALOG TO DIGITAL CONVERSION')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CONVERTButton = uibutton(app.UIFigure, 'push');
            app.CONVERTButton.ButtonPushedFcn = createCallbackFcn(app, @CONVERTButtonPushed, true);
            app.CONVERTButton.Text = 'CONVERT';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'text');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Placeholder = '10';
            app.BitCountEditFieldLabel = uilabel(app.UIFigure);
            app.BitCountEditFieldLabel.Text = 'Bit Count';
            app.BitCountEditField = uieditfield(app.UIFigure, 'text');
            app.BitCountEditField.ValueChangedFcn = createCallbackFcn(app, @BitCountEditFieldValueChanged, true);
            app.BitCountEditField.Placeholder = '3';
            app.DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label = uilabel(app.UIFigure);
            app.DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label.Text = 'DEVELOPED BY: MD SAIFUL ISLAM RIMON (213002039) & NAZMUN NAHAR (191000000)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'PROJECT SUPERVISOR: RUSMITA HALIM CHAITY,  LECTURER, DEPT. OF CSE, GUB';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'images__1_-removebg-preview.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Logo-PNG-1.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end