classdef BitStuffingDestuffing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        Label                       matlab.ui.control.Label
        DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label  matlab.ui.control.Label
        BitStuffingDestuffingLabel  matlab.ui.control.Label
        OUTPUTLabel                 matlab.ui.control.Label
        OUTPUTEditField             matlab.ui.control.EditField
        DESTUFFINGButton            matlab.ui.control.Button
        STUFFINGButton              matlab.ui.control.Button
        INPUTEditField              matlab.ui.control.EditField
        INPUTEditFieldLabel         matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        OUTPUTEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        
    end
    
    methods (Access = public)
        
        function bitstuffing(app)
            inputFrame = app.INPUTEditField.Value;
            stuffedFrame = bitStuffing(app, inputFrame);
            app.OUTPUTEditField.Value = stuffedFrame;
        end

        function bitdestuffing(app)
            inputFrame = app.INPUTEditField.Value;
            destuffedFrame = bitDestuffing(app, stuffedFrame);
            app.OUTPUTEditField.Value = destuffedFrame;
        end

        function stuffedFrame = bitStuffing(app, frame)
    % Perform bit stuffing
    stuffedFrame = [];
    count = 0;

    for bit = frame
        if bit == 1
            count = count + 1;
        else
            count = 0;
        end

        stuffedFrame = [stuffedFrame, bit];

        % Check for five consecutive ones and stuff a 0 bit
        if count == 5
            stuffedFrame = [stuffedFrame, 0];
            count = 0;
        end
    end
end

        function destuffedFrame = bitDestuffing(app, frame)
    % Perform bit destuffing
    i = 1;
    count = 0;
    while i <= length(frame)
        if frame(i) == 1
            count = count + 1;
        else
            count = 0;
        end
        if count == 4 && (frame(i) == 0 || i == length(frame))
            continue;
        end
        count = 0;
        i = i + 1;
    end
    destuffedFrame = frame;
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OUTPUTEditField
        function OUTPUTEditFieldValueChanged(app, event)
            value = app.OUTPUTEditField.Value;
            
        end

        % Value changed function: INPUTEditField
        function INPUTEditFieldValueChanged(app, event)
            value = app.INPUTEditField.Value;
            
        end

        % Button pushed function: STUFFINGButton
        function STUFFINGButtonPushed(app, event)
            bitstuffing(app);

        end

        % Button pushed function: DESTUFFINGButton
        function DESTUFFINGButtonPushed(app, event)
            bitdestuffing(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OUTPUTEditFieldLabel = uilabel(app.UIFigure);
            app.OUTPUTEditFieldLabel.Text = 'OUTPUT';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'bg.jpg');
            app.INPUTEditFieldLabel = uilabel(app.UIFigure);
            app.INPUTEditFieldLabel.Text = 'INPUT';
            app.INPUTEditField = uieditfield(app.UIFigure, 'text');
            app.INPUTEditField.ValueChangedFcn = createCallbackFcn(app, @INPUTEditFieldValueChanged, true);
            app.INPUTEditField.Placeholder = 'UNITEDLEDSTATES';
            app.STUFFINGButton = uibutton(app.UIFigure, 'push');
            app.STUFFINGButton.ButtonPushedFcn = createCallbackFcn(app, @STUFFINGButtonPushed, true);
            app.STUFFINGButton.Text = 'STUFFING';
            app.DESTUFFINGButton = uibutton(app.UIFigure, 'push');
            app.DESTUFFINGButton.ButtonPushedFcn = createCallbackFcn(app, @DESTUFFINGButtonPushed, true);
            app.DESTUFFINGButton.Text = 'DESTUFFING';
            app.OUTPUTEditField = uieditfield(app.UIFigure, 'text');
            app.OUTPUTEditField.ValueChangedFcn = createCallbackFcn(app, @OUTPUTEditFieldValueChanged, true);
            app.OUTPUTEditField.Editable = 'off';
            app.OUTPUTEditField.Placeholder = 'Output will show here';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.BitStuffingDestuffingLabel = uilabel(app.UIFigure);
            app.BitStuffingDestuffingLabel.Text = 'Bit Stuffing & Destuffing';
            app.DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label = uilabel(app.UIFigure);
            app.DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label.Text = 'DEVELOPED BY: MD SAIFUL ISLAM RIMON (213002039) & NAZMUN NAHAR (191000000)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'PROJECT SUPERVISOR: RUSMITA HALIM CHAITY,  LECTURER, DEPT. OF CSE, GUB';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'images__1_-removebg-preview.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Logo-PNG-1.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitStuffingDestuffing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end