classdef CharacterStuffingDestuffing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image3                matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        Label                 matlab.ui.control.Label
        DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label  matlab.ui.control.Label
        CharacterStuffingDestuffingLabel  matlab.ui.control.Label
        OUTPUTLabel           matlab.ui.control.Label
        OUTPUTEditField       matlab.ui.control.EditField
        DESTUFFINGButton      matlab.ui.control.Button
        STUFFINGButton        matlab.ui.control.Button
        INPUTEditField        matlab.ui.control.EditField
        INPUTEditFieldLabel   matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        OUTPUTEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        globalvariable;
        d;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OUTPUTEditField
        function OUTPUTEditFieldValueChanged(app, event)
            value = app.OUTPUTEditField.Value;
            
        end

        % Value changed function: INPUTEditField
        function INPUTEditFieldValueChanged(app, event)
            value = app.INPUTEditField.Value;
            
        end

        % Button pushed function: STUFFINGButton
        function STUFFINGButtonPushed(app, event)
i = 1;
j = 1;
app.globalvariable = 0;
app.d = blanks(100);
l = 'DLEETX';
sd = blanks(100);
ds = blanks(100);


app.d = app.INPUTEditField.Value;

sd(1:6) = 'DLESTX';
j = 7;

while i <= length(app.d)
    if app.d(i) == 'D' && app.d(i + 1) == 'L' && app.d(i + 2) == 'E'
        sd(j:j+5) = 'DLEDLE';
        j = j + 6;
        i = i + 3;
    else
        sd(j) = app.d(i);
        j = j + 1;
        i = i + 1;
    end
end

sd(j) = char(0);
ds = sd;

app.globalvariable = j;
ds = strcat(ds, l);

app.OUTPUTEditField.Value = ds;

        end

        % Button pushed function: DESTUFFINGButton
        function DESTUFFINGButtonPushed(app, event)
ds = app.INPUTEditField.Value;
i = 1;
l = 'DLEETX';
sd(1:6) = 'DLESTX';
j = app.globalvariable;
p = blanks(100); %addedlater
sd = blanks(100);
ds = blanks(100);
k = j;
while j <= length(ds) && ds(j) ~= 0
    if ds(j) == 'D' && ds(j + 1) == 'L' && ds(j + 2) == 'E'
        if ds(j + 3) == 'D' && ds(j + 4) == 'L' && ds(j + 5) == 'E'
            % Remove "DLE DLE" by shifting remaining characters
            ds(k:j) = ds(j + 6:end);
            % Update loop condition and string length
            j = k;
            ds = ds(1:k);
        else
            % Found single "DLE", copy character (check index bounds)
            if i <= length(p)
                ds(k) = p(i);
                i = i + 1;
                k = k + 1;
            end
        end
    else
        % Copy character (check index bounds)
        if i <= length(p)
            ds(k) = p(i);
            i = i + 1;
            k = k + 1;
        end
    end
    j = j + 1;
end

app.OUTPUTEditField.Value = app.d;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OUTPUTEditFieldLabel = uilabel(app.UIFigure);
            app.OUTPUTEditFieldLabel.Text = 'OUTPUT';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'bg.jpg');
            app.INPUTEditFieldLabel = uilabel(app.UIFigure);
            app.INPUTEditFieldLabel.Text = 'INPUT';
            app.INPUTEditField = uieditfield(app.UIFigure, 'text');
            app.INPUTEditField.ValueChangedFcn = createCallbackFcn(app, @INPUTEditFieldValueChanged, true);
            app.INPUTEditField.Placeholder = 'UNITEDLEDSTATES';
            app.STUFFINGButton = uibutton(app.UIFigure, 'push');
            app.STUFFINGButton.ButtonPushedFcn = createCallbackFcn(app, @STUFFINGButtonPushed, true);
            app.STUFFINGButton.Text = 'STUFFING';
            app.DESTUFFINGButton = uibutton(app.UIFigure, 'push');
            app.DESTUFFINGButton.ButtonPushedFcn = createCallbackFcn(app, @DESTUFFINGButtonPushed, true);
            app.DESTUFFINGButton.Text = 'DESTUFFING';
            app.OUTPUTEditField = uieditfield(app.UIFigure, 'text');
            app.OUTPUTEditField.ValueChangedFcn = createCallbackFcn(app, @OUTPUTEditFieldValueChanged, true);
            app.OUTPUTEditField.Editable = 'off';
            app.OUTPUTEditField.Placeholder = 'Output will show here';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.CharacterStuffingDestuffingLabel = uilabel(app.UIFigure);
            app.CharacterStuffingDestuffingLabel.Text = 'Character Stuffing & Destuffing';
            app.DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label = uilabel(app.UIFigure);
            app.DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label.Text = 'DEVELOPED BY: MD SAIFUL ISLAM RIMON (213002039) & NAZMUN NAHAR (191000000)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'PROJECT SUPERVISOR: RUSMITA HALIM CHAITY,  LECTURER, DEPT. OF CSE, GUB';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'images__1_-removebg-preview.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Logo-PNG-1.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CharacterStuffingDestuffing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end