classdef crcapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputBitStringLabel             matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        CyclicRedundancyCheckCRCLabel   matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ErrorStatusEditField            matlab.ui.control.EditField
        ErrorStatusEditFieldLabel       matlab.ui.control.Label
        CheckButton                     matlab.ui.control.Button
        ReceivedCodewordEditField       matlab.ui.control.EditField
        ReceivedCodewordEditFieldLabel  matlab.ui.control.Label
        GenerateTransmittingCodewordButton  matlab.ui.control.Button
        TransmittingCodewordEditField   matlab.ui.control.EditField
        TransmittingCodewordLabel       matlab.ui.control.Label
        InputPolynomialinBinaryEditField  matlab.ui.control.EditField
        InputPolynomialinBinaryEditFieldLabel  matlab.ui.control.Label
        InputBitStingEditField          matlab.ui.control.EditField
        Image                           matlab.ui.control.Image
        InputBitStingEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        gen_poly
    end
    
    methods (Access = public)
        
        function crc_matlab_sender(app)
    % Define the data and polynomial
    data = app.InputBitStingEditField.Value;
    app.gen_poly = app.InputPolynomialinBinaryEditField.Value;
    
    data_length = length(data);
    N = length(app.gen_poly);
    
    % Append zeros to the data
    appended_data = [data, repmat('0', 1, N-1)];
    
    % Perform CRC
    remainder = crc_division(app, appended_data, app.gen_poly);
    
    % Generate codeword
    codeword = [data, remainder];
    
    app.TransmittingCodewordEditField.Value = codeword;

        end

        function crc_matlab_receiver(app)
    % Define the data and polynomial
	    received_data = app.ReceivedCodewordEditField.Value;
    	received_remainder = crc_division(app, received_data, app.gen_poly);
    
   	 if any(received_remainder ~= '0')
        	app.ErrorStatusEditField.Value = 'Error Detected';
            app.Lamp.Color = [1.00,0.00,0.00];
           
    	else
        	app.ErrorStatusEditField.Value = 'No Error Detected';
            app.Lamp.Color = [0.00,1.00,0.00];
    	end

      end


function remainder = crc_division(app, input_data, gen_poly)
    N = length(gen_poly);
    temp_data = input_data(1:N);
    
    for i = 1:length(input_data) - N + 1
        if temp_data(1) == '1'
            temp_data = xor_strings(app, temp_data, gen_poly);
        end
        
        if i+N <= length(input_data)
            temp_data = [temp_data(2:end), input_data(i+N)];
        else
            temp_data = temp_data(2:end);
        end
    end
    
    remainder = temp_data;
end

function result = xor_strings(app, a, b)
    result = char(xor(a-'0', b-'0') + '0');
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputBitStingEditField
        function InputBitStingEditFieldValueChanged(app, event)
            value = app.InputBitStingEditField.Value;
            
        end

        % Value changed function: InputPolynomialinBinaryEditField
        function InputPolynomialinBinaryEditFieldValueChanged(app, event)
            value = app.InputPolynomialinBinaryEditField.Value;
            
        end

        % Button pushed function: GenerateTransmittingCodewordButton
        function GenerateTransmittingCodewordButtonPushed(app, event)
            crc_matlab_sender(app);
    

        end

        % Value changed function: TransmittingCodewordEditField
        function TransmittingCodewordEditFieldValueChanged(app, event)
            value = app.TransmittingCodewordEditField.Value;
            
        end

        % Value changed function: ReceivedCodewordEditField
        function ReceivedCodewordEditFieldValueChanged(app, event)
            value = app.ReceivedCodewordEditField.Value;
            
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            crc_matlab_receiver(app)
        end

        % Value changed function: ErrorStatusEditField
        function ErrorStatusEditFieldValueChanged(app, event)
            value = app.ErrorStatusEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputBitStingEditFieldLabel = uilabel(app.UIFigure);
            app.InputBitStingEditFieldLabel.Text = 'Input Bit Sting';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'bg.jpg';
            app.InputBitStingEditField = uieditfield(app.UIFigure, 'text');
            app.InputBitStingEditField.ValueChangedFcn = createCallbackFcn(app, @InputBitStingEditFieldValueChanged, true);
            app.InputBitStingEditField.Placeholder = '1001010';
            app.InputPolynomialinBinaryEditFieldLabel = uilabel(app.UIFigure);
            app.InputPolynomialinBinaryEditFieldLabel.Text = 'Input Polynomial in Binary';
            app.InputPolynomialinBinaryEditField = uieditfield(app.UIFigure, 'text');
            app.InputPolynomialinBinaryEditField.ValueChangedFcn = createCallbackFcn(app, @InputPolynomialinBinaryEditFieldValueChanged, true);
            app.InputPolynomialinBinaryEditField.Placeholder = '1011';
            app.TransmittingCodewordLabel = uilabel(app.UIFigure);
            app.TransmittingCodewordLabel.Text = {'Transmitting'; 'Codeword'};
            app.TransmittingCodewordEditField = uieditfield(app.UIFigure, 'text');
            app.TransmittingCodewordEditField.ValueChangedFcn = createCallbackFcn(app, @TransmittingCodewordEditFieldValueChanged, true);
            app.TransmittingCodewordEditField.Editable = 'off';
            app.TransmittingCodewordEditField.Placeholder = 'Shows Transmitting Codeword';
            app.GenerateTransmittingCodewordButton = uibutton(app.UIFigure, 'push');
            app.GenerateTransmittingCodewordButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTransmittingCodewordButtonPushed, true);
            app.GenerateTransmittingCodewordButton.Text = 'Generate Transmitting Codeword';
            app.ReceivedCodewordEditFieldLabel = uilabel(app.UIFigure);
            app.ReceivedCodewordEditFieldLabel.Text = 'Received Codeword';
            app.ReceivedCodewordEditField = uieditfield(app.UIFigure, 'text');
            app.ReceivedCodewordEditField.ValueChangedFcn = createCallbackFcn(app, @ReceivedCodewordEditFieldValueChanged, true);
            app.ReceivedCodewordEditField.Placeholder = '1001010111';
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.ErrorStatusEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorStatusEditFieldLabel.Text = 'Error Status';
            app.ErrorStatusEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorStatusEditField.ValueChangedFcn = createCallbackFcn(app, @ErrorStatusEditFieldValueChanged, true);
            app.ErrorStatusEditField.Editable = 'off';
            app.ErrorStatusEditField.Placeholder = 'Shows Error Status';
            app.Lamp = uilamp(app.UIFigure);
            app.CyclicRedundancyCheckCRCLabel = uilabel(app.UIFigure);
            app.CyclicRedundancyCheckCRCLabel.Text = 'Cyclic Redundancy Check (CRC)';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'images__1_-removebg-preview.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Logo-PNG-1.png';
            app.DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label = uilabel(app.UIFigure);
            app.DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label.Text = 'DEVELOPED BY: MD SAIFUL ISLAM RIMON (213002039) & NAZMUN NAHAR (191000000)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'PROJECT SUPERVISOR: RUSMITA HALIM CHAITY,  LECTURER, DEPT. OF CSE, GUB';
            app.InputBitStringLabel = uilabel(app.UIFigure);
            app.InputBitStringLabel.Text = 'Input Bit String';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crcapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end