classdef home < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DeveloperButton                 matlab.ui.control.Button
        FeedbackButton                  matlab.ui.control.Button
        DocumentationButton             matlab.ui.control.Button
        EXITButton                      matlab.ui.control.Button
        CRCButton                       matlab.ui.control.Button
        BITSTUFFINGDESTUFFINGButton     matlab.ui.control.Button
        CHARACTERSTUFFINGDESTUFFINGButton  matlab.ui.control.Button
        HAMMINGENCODINGDECODINGButton   matlab.ui.control.Button
        IPv4CONVERSIONButton_2          matlab.ui.control.Button
        ANALOGTODIGITALSIGNALCONVERSIONButton  matlab.ui.control.Button
        LINECODINGButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        DATATRANSMISSIONSIMULATORLabel  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LINECODINGButton
        function LINECODINGButtonPushed(app, event)
            app.callingapp = app1;
        end

        % Button pushed function: IPv4CONVERSIONButton_2
        function IPv4CONVERSIONButton_2Pushed(app, event)
            app.callingapp = ipv4;
        end

        % Button pushed function: HAMMINGENCODINGDECODINGButton
        function HAMMINGENCODINGDECODINGButtonPushed(app, event)
            app.callingapp = hammingcode;
        end

        % Button pushed function: ANALOGTODIGITALSIGNALCONVERSIONButton
        function ANALOGTODIGITALSIGNALCONVERSIONButtonPushed(app, event)
             app.callingapp = ADC;
        end

        % Button pushed function: CHARACTERSTUFFINGDESTUFFINGButton
        function CHARACTERSTUFFINGDESTUFFINGButtonPushed(app, event)
            app.callingapp = CharacterStuffingDestuffing;
        end

        % Button pushed function: BITSTUFFINGDESTUFFINGButton
        function BITSTUFFINGDESTUFFINGButtonPushed(app, event)
            
        end

        % Button pushed function: CRCButton
        function CRCButtonPushed(app, event)
            app.callingapp = crcapp;
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            % Close all figure windows
        figHandles = findall(0, 'Type', 'figure');
        delete(figHandles);
        end

        % Button pushed function: DocumentationButton
        function DocumentationButtonPushed(app, event)
            % Specify the URL you want to open
            url = 'https://drive.google.com/file/d/1bF9JgSCLfZFA0Z-P34lV6rVu_HtL-qaI/view?usp=sharing';

        % Open the link in the default web browser
            web(url, '-browser');
        end

        % Button pushed function: FeedbackButton
        function FeedbackButtonPushed(app, event)
           email = 'saifulislamrimon2014@gmail.com';

        % Create a "mailto" link
        mailtoLink = ['mailto:' email];

        % Open the link in the default email client
        web(mailtoLink, '-browser');
        end

        % Button pushed function: DeveloperButton
        function DeveloperButtonPushed(app, event)
                         % Specify the URL you want to open
            url = 'sites.google.com/view/mdsaifulislamrimon';

        % Open the link in the default web browser
            web(url, '-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'bg.jpg');
            app.DATATRANSMISSIONSIMULATORLabel = uilabel(app.UIFigure);
            app.DATATRANSMISSIONSIMULATORLabel.Text = 'DATA TRANSMISSION SIMULATOR';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo-PNG.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'cse-dept-logo.png');
            app.DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label = uilabel(app.UIFigure);
            app.DEVELOPEDBYMDSAIFULISLAMRIMON213002039NAZMUNNAHAR191000000Label.Text = 'DEVELOPED BY: MD SAIFUL ISLAM RIMON (213002039) & NAZMUN NAHAR (191000000)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'PROJECT SUPERVISOR: RUSMITA HALIM CHAITY,  LECTURER, DEPT. OF CSE, GUB';
            app.LINECODINGButton = uibutton(app.UIFigure, 'push');
            app.LINECODINGButton.ButtonPushedFcn = createCallbackFcn(app, @LINECODINGButtonPushed, true);
            app.LINECODINGButton.Text = 'LINE CODING';
            app.ANALOGTODIGITALSIGNALCONVERSIONButton = uibutton(app.UIFigure, 'push');
            app.ANALOGTODIGITALSIGNALCONVERSIONButton.ButtonPushedFcn = createCallbackFcn(app, @ANALOGTODIGITALSIGNALCONVERSIONButtonPushed, true);
            app.ANALOGTODIGITALSIGNALCONVERSIONButton.Text = {'ANALOG TO DIGITAL'; 'SIGNAL CONVERSION'};
            app.IPv4CONVERSIONButton_2 = uibutton(app.UIFigure, 'push');
            app.IPv4CONVERSIONButton_2.ButtonPushedFcn = createCallbackFcn(app, @IPv4CONVERSIONButton_2Pushed, true);
            app.IPv4CONVERSIONButton_2.Text = 'IPv4 CONVERSION';
            app.HAMMINGENCODINGDECODINGButton = uibutton(app.UIFigure, 'push');
            app.HAMMINGENCODINGDECODINGButton.ButtonPushedFcn = createCallbackFcn(app, @HAMMINGENCODINGDECODINGButtonPushed, true);
            app.HAMMINGENCODINGDECODINGButton.Text = {'HAMMING ENCODING &'; 'DECODING'};
            app.CHARACTERSTUFFINGDESTUFFINGButton = uibutton(app.UIFigure, 'push');
            app.CHARACTERSTUFFINGDESTUFFINGButton.ButtonPushedFcn = createCallbackFcn(app, @CHARACTERSTUFFINGDESTUFFINGButtonPushed, true);
            app.CHARACTERSTUFFINGDESTUFFINGButton.Text = {'CHARACTER STUFFING &'; 'DESTUFFING'};
            app.BITSTUFFINGDESTUFFINGButton = uibutton(app.UIFigure, 'push');
            app.BITSTUFFINGDESTUFFINGButton.ButtonPushedFcn = createCallbackFcn(app, @BITSTUFFINGDESTUFFINGButtonPushed, true);
            app.BITSTUFFINGDESTUFFINGButton.Text = {'BIT STUFFING &'; 'DESTUFFING'};
            app.CRCButton = uibutton(app.UIFigure, 'push');
            app.CRCButton.ButtonPushedFcn = createCallbackFcn(app, @CRCButtonPushed, true);
            app.CRCButton.Text = 'CRC';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.DocumentationButton = uibutton(app.UIFigure, 'push');
            app.DocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @DocumentationButtonPushed, true);
            app.DocumentationButton.Text = 'Documentation';
            app.FeedbackButton = uibutton(app.UIFigure, 'push');
            app.FeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @FeedbackButtonPushed, true);
            app.FeedbackButton.Text = 'Feedback';
            app.DeveloperButton = uibutton(app.UIFigure, 'push');
            app.DeveloperButton.ButtonPushedFcn = createCallbackFcn(app, @DeveloperButtonPushed, true);
            app.DeveloperButton.Text = 'Developer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = home

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end